classdef GUI_v2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        WriteFileButton                matlab.ui.control.Button
        OutputStatusField              matlab.ui.control.EditField
        OutputButton                   matlab.ui.control.Button
        InputStatusField               matlab.ui.control.EditField
        RealTimeButton                 matlab.ui.control.Button
        LoadFileButton                 matlab.ui.control.Button
        RecordButton                   matlab.ui.control.Button
        OutputDropDown                 matlab.ui.control.DropDown
        OutputDropDownLabel            matlab.ui.control.Label
        InputDropDown                  matlab.ui.control.DropDown
        InputDropDownLabel             matlab.ui.control.Label
        TabGroup                       matlab.ui.container.TabGroup
        PlotsTab                       matlab.ui.container.Tab
        UpdateButton                   matlab.ui.control.Button
        OutputFrequencyDomainAxis      matlab.ui.control.UIAxes
        OutputTimeDomainAxis           matlab.ui.control.UIAxes
        ImpulseResponseFrequencyDomainAxis  matlab.ui.control.UIAxes
        ImpulseResponseTimeDomainAxis  matlab.ui.control.UIAxes
        InputFrequencyDomainAxis       matlab.ui.control.UIAxes
        InputTimeDomainAxis            matlab.ui.control.UIAxes
        RoomAcousticalRenderingTab     matlab.ui.container.Tab
        StopPosRecordingButton         matlab.ui.control.Button
        RecordPosStatusField           matlab.ui.control.EditField
        HrirStatusField                matlab.ui.control.EditField
        LoadHRIRSetButton              matlab.ui.control.Button
        RecordPositionsButton          matlab.ui.control.Button
        ShowraytracesCheckBox          matlab.ui.control.CheckBox
        PlotimagesourcesCheckBox       matlab.ui.control.CheckBox
        KeystrokesButton               matlab.ui.control.Button
        ApplyCoordsButton              matlab.ui.control.Button
        ReceiverZField                 matlab.ui.control.NumericEditField
        ZEditField_2Label              matlab.ui.control.Label
        ReceiverYField                 matlab.ui.control.NumericEditField
        YEditField_2Label              matlab.ui.control.Label
        ReceiverXField                 matlab.ui.control.NumericEditField
        XEditField_4Label              matlab.ui.control.Label
        ReceiverLabel                  matlab.ui.control.Label
        SourceZField                   matlab.ui.control.NumericEditField
        ZEditFieldLabel                matlab.ui.control.Label
        SourceYField                   matlab.ui.control.NumericEditField
        YEditFieldLabel                matlab.ui.control.Label
        SourceXField                   matlab.ui.control.NumericEditField
        XEditFieldLabel                matlab.ui.control.Label
        SourceLabel                    matlab.ui.control.Label
        RenderCuboidRoomButton         matlab.ui.control.Button
        RoomPlot                       matlab.ui.control.UIAxes
        SpatializationTab              matlab.ui.container.Tab
        SenderReceiverTab              matlab.ui.container.Tab
    end

    
    properties (Access = private)
        audioRecorder;
        audioRealtimePlayerRecorder;
        recordingFlag = false;

        inputAudioData;
        outputAudioData;
        f_s;
        combinedRoomImpulseResponse;
        t_reverb;
        
        roomDimensions;
        roomType;
        Source;
        Receiver;
        recordedSourceCoords;
        recordedReceiverCoords
        ImageSourceCoords
        Wallcoefs;
        
        HrirFullSet
        CurrentHrirLeft
        CurrentHrirRight
        
        boKeystrokeActive;
        boRecordingPositions = false;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: InputDropDown
        function InputDropDownValueChanged(app, event)
            if "Microphone" == app.InputDropDown.Value
                app.RecordButton.Visible = "on";
                app.LoadFileButton.Visible = "off";
                app.RealTimeButton.Visible = "off";
            elseif "File" == app.InputDropDown.Value
                app.RecordButton.Visible = "off";
                app.LoadFileButton.Visible = "on";
                app.RealTimeButton.Visible = "off";
            elseif "Real-Time" == app.InputDropDown.Value
                app.RecordButton.Visible = "off";
                app.LoadFileButton.Visible = "off";
                app.RealTimeButton.Visible = "on";
            end
            
        end

        % Button pushed function: RecordButton
        function RecordButtonPushed(app, event)
            if false == app.recordingFlag
                if isempty(app.f_s)
                    app.f_s = 21000;
                end

                app.audioRecorder = audiorecorder(app.f_s, 24, 1);
                app.recordingFlag = recordAudio(app.audioRecorder, app.f_s, app.recordingFlag);
                app.InputStatusField.Value = "recording";
            else
                app.recordingFlag = recordAudio(app.audioRecorder, app.f_s, app.recordingFlag);
                app.InputStatusField.Value = "finished";
                app.inputAudioData = getaudiodata(app.audioRecorder);
            end
        end

        % Value changed function: OutputDropDown
        function OutputDropDownValueChanged(app, event)
            if "Output" == app.OutputDropDown.Value
                app.OutputButton.Visible = "on";
                app.WriteFileButton.Visible = "off";
            elseif "File" == app.OutputDropDown.Value
                app.OutputButton.Visible = "off";
                app.WriteFileButton.Visible = "on";
            end
            
        end

        % Button pushed function: WriteFileButton
        function WriteFileButtonPushed(app, event)
            if ~isempty(app.outputAudioData)
                boStatusFlag = writeAudiofile("wav", app.outputAudioData, app.f_s);
            elseif isempty(app.outputAudioData) && ~isempty(app.inputAudioData)
                boStatusFlag = writeAudiofile("wav", app.inputAudioData, app.f_s);
            else
                boStatusFlag = false;
            end

            if true == boStatusFlag
                app.OutputStatusField.Value = "written";
            else
                app.OutputStatusField.Value = "failed";
            end
        end

        % Button pushed function: OutputButton
        function OutputButtonPushed(app, event)
            if ~isempty(app.outputAudioData)
                boStatusFlag = writeAudiofile("speaker", app.outputAudioData, app.f_s);
            elseif isempty(app.outputAudioData) && ~isempty(app.inputAudioData)
                boStatusFlag = writeAudiofile("speaker", app.inputAudioData, app.f_s);
            else
                boStatusFlag = false;
            end

            if true == boStatusFlag
                app.OutputStatusField.Value = "played";
            else
                app.OutputStatusField.Value = "failed";
            end
        end

        % Button pushed function: LoadFileButton
        function LoadFileButtonPushed(app, event)
            [file,filePath] = uigetfile({'*.wav; *.mp3; *.flac', 'Audio files (*.wav, *.mp3, *.flac)'});
            if isequal(file, 0)
                app.InputStatusField.Value = "failed";
            else
                try
                    [app.inputAudioData, app.f_s] = audioread(fullfile(filePath, file));
                    app.InputStatusField.Value = "read";
                catch
                    app.InputStatusField.Value = "failed";
                end
            end
        end

        % Button pushed function: RenderCuboidRoomButton
        function RenderCuboidRoomButtonPushed(app, event)
            popup = uifigure("Name", "Choose room type", "Position", [100, 100, 300, 200]);
            dropdownLabel = uilabel(popup, 'Text', 'Select a room', 'Position', [10, 130, 150, 22]);
            dropdown = uidropdown(popup, "Items", {'100x100x100, high reflection', '100x100x100, low reflection','300x300x300, high reflection', '300x300x300, low reflection', 'Custom'}, "Position", [150, 130, 150, 22]);
            reverbTFieldLabel = uilabel(popup, 'Text', 'Reverberation time [s]:', 'Position', [10, 70, 150, 22]);
            reverbT_field  = uieditfield(popup, 'numeric', 'Position', [150, 70, 50, 22], 'Value', 0);
            sampleRFieldLabel = uilabel(popup, 'Text', 'Sample rate [Hz]:', 'Position', [10, 100, 150, 22]);
            sampleR_field = uieditfield(popup, 'numeric', 'Position', [150, 100, 50, 22], 'Value', 0);
            popupButton = uibutton(popup, "Text", "Ok", "Position", [95, 20, 60, 22], 'ButtonPushedFcn', @(~,~) popupButtonPushedFcn(popup, dropdown, reverbT_field, sampleR_field));
            
            function popupButtonPushedFcn(popup, dropdown, reverbT_field, sampleR_field)
                if ~isempty(app.inputAudioData) && ~isempty(app.f_s)
                    app.inputAudioData= resample(app.inputAudioData, sampleR_field.Value, app.f_s);
                end
                 if ~isempty(app.HrirFullSet) 
                    for i = 1:length(app.HrirFullSet(:,1))
                        for j = 1:length(app.HrirFullSet{i})
                            app.HrirFullSet{i}{j} = resample(app.HrirFullSet{i}{j}, sampleR_field.Value, 44100);
                            app.HrirStatusField.Value = "Set resampled";
                        end
                    end
                end
                app.t_reverb = reverbT_field.Value;
                app.f_s = sampleR_field.Value;
                app.roomType = dropdown.Value;
                cla(app.RoomPlot);
                close(popup);
            end
            waitfor(popup);
        
            if strcmp("Custom", app.roomType)
            % get room dimensions
            promt = {"Room length:", "Room width:", "Room height:"};
            title = "Enter room dimensions";
            dims = [1 80];
            defaultValues = {'', '', ''};
    
                inputs = inputdlg(promt, title, dims, defaultValues);
                if ~isempty(inputs)
                app.roomDimensions = [str2double(inputs(1)), str2double(inputs(2)), str2double(inputs(3))];
                end
                
                % get source position
                promt = {"Source x coord:", "Source y coord:", "Source z coord:"};
                title = "Source location in room";
                dims = [1 80];
                defaultValues = {'', '', ''};
    
                inputs = inputdlg(promt, title, dims, defaultValues);
                if ~isempty(inputs)
                app.Source = [str2double(inputs(1)), str2double(inputs(2)), str2double(inputs(3))];
                end
    
                % get source position
                promt = {"Receiver x coord:", "Receiver y coord:", "Receiver z coord:"};
                title = "Receiver location in room";
                dims = [1 80];
                defaultValues = {'', '', ''};
    
                inputs = inputdlg(promt, title, dims, defaultValues);
                if ~isempty(inputs)
                app.Receiver = [str2double(inputs(1)), str2double(inputs(2)), str2double(inputs(3))];
                end
    
                % get Wall coefficients
                promt = {"Left wall:", "Right wall:", "Front wall:", "Back wall:", "Floor:", "Ceiling:"};
                title = "Absorption coefficients of walls";
                dims = [1 80];
                defaultValues = {'', '', '', '', '', ''};
    
                inputs = inputdlg(promt, title, dims, defaultValues);
                if ~isempty(inputs)
                app.Wallcoefs = [str2double(inputs(1)), str2double(inputs(2)), str2double(inputs(3)), str2double(inputs(4)), str2double(inputs(5)), str2double(inputs(6))];
                end
            elseif strcmp('100x100x100, high reflection', app.roomType)
                app.roomDimensions = [100, 100, 100];
                app.Source = [50, 50, 50];
                app.Receiver = [1, 55, 50];
                app.Wallcoefs = [0.2, 0.2, 0.2, 0.2, 0.2, 0.2];

            elseif strcmp('100x100x100, low reflection', app.roomType)
                app.roomDimensions = [100, 100, 100];
                app.Source = [50, 50, 50];
                app.Receiver = [1, 55, 50];
                app.Wallcoefs = [0.1, 0.1, 0.1, 0.1, 0.1, 0.1];
            elseif strcmp('300x300x300, high reflection', app.roomType)
                app.roomDimensions = [300, 300, 300];
                app.Source = [150, 150, 150];
                app.Receiver = [10, 155, 150];
                app.Wallcoefs = [0.2, 0.2, 0.2, 0.2, 0.2, 0.2];          
            elseif strcmp('300x300x300, low reflection', app.roomType)
                app.roomDimensions = [300, 300, 300];
                app.Source = [150, 150, 150];
                app.Receiver = [10, 155, 150];
                app.Wallcoefs = [0.1, 0.1, 0.1, 0.1, 0.1, 0.1];     
            else
                return
            end
            
            if ~isempty(app.roomDimensions) && ~isempty(app.Source) && ~isempty(app.Receiver) && ~isempty(app.Wallcoefs) && ~isempty(app.t_reverb) && ~isempty(app.f_s)
                [app.combinedRoomImpulseResponse, app.ImageSourceCoords]  = IRfromCuboid(app.roomDimensions, app.Source, app.Receiver, app.t_reverb, app.Wallcoefs, app.f_s);
                app.SourceXField.Value = app.Source(1);
                app.SourceYField.Value = app.Source(2);
                app.SourceZField.Value = app.Source(3);
                app.ReceiverXField.Value = app.Receiver(1);
                app.ReceiverYField.Value = app.Receiver(2);
                app.ReceiverZField.Value = app.Receiver(3);

                if app.PlotimagesourcesCheckBox.Value
                    plotImageSources(app.roomDimensions, app.Receiver, app.Source, app.ImageSourceCoords, app.ShowraytracesCheckBox.Value, app.RoomPlot);
                else
                    plotRoom(app.roomDimensions, app.Receiver, app.Source, app.RoomPlot);
                end
            end
        end

        % Button pushed function: KeystrokesButton
        function KeystrokesButtonPushed(app, event)
            if ~app.boKeystrokeActive
                app.boKeystrokeActive = true;
                app.KeystrokesButton.Text = 'Deactivate';
            else
                app.boKeystrokeActive = false;
                app.KeystrokesButton.Text = 'Keystrokes';
            end
        end

        % Key press function: UIFigure
        function KeyPressCallback(app, event)
            if app.boKeystrokeActive
            [app.Source, app.Receiver] = keystrokeCoordUpdate(app.Source, app.Receiver, app.roomDimensions, event);
            [app.combinedRoomImpulseResponse, app.ImageSourceCoords]  = IRfromCuboid(app.roomDimensions, app.Source, app.Receiver, app.t_reverb, app.Wallcoefs, app.f_s);
            
            app.SourceXField.Value = app.Source(1);
            app.SourceYField.Value = app.Source(2);
            app.SourceZField.Value = app.Source(3);
            app.ReceiverXField.Value = app.Receiver(1);
            app.ReceiverYField.Value = app.Receiver(2);
            app.ReceiverZField.Value = app.Receiver(3);

            cla(app.RoomPlot);
            if app.PlotimagesourcesCheckBox.Value
                plotImageSources(app.roomDimensions, app.Receiver, app.Source, app.ImageSourceCoords, app.ShowraytracesCheckBox.Value, app.RoomPlot);
            else
                plotRoom(app.roomDimensions, app.Receiver, app.Source, app.RoomPlot);
            end
                % [app.current_hrir_left, app.current_hrir_right] = computeFinalHrir(app.Receiver, app.Source, app.hrir_fullset, app.F_s_2); computeFinalImpulseResponse(app);
            end  
        end

        % Button pushed function: ApplyCoordsButton
        function ApplyCoordsButtonPushed(app, event)
        if ~isempty(app.SourceXField.Value)
            app.Source(1) = app.SourceXField.Value;
        end
        if ~isempty(app.SourceYField.Value)
            app.Source(2) = app.SourceYField.Value;
        end
        if ~isempty(app.SourceZField.Value)
            app.Source(3) = app.SourceZField.Value;
        end            
        if ~isempty(app.ReceiverXField.Value)
            app.Receiver(1) = app.ReceiverXField.Value;
        end
        if ~isempty(app.ReceiverYField.Value)
            app.Source(2) = app.ReceiverYField.Value;
        end
        if ~isempty(app.ReceiverZField.Value)
            app.Source(3) = app.ReceiverZField.Value;
        end          

        [app.combinedRoomImpulseResponse, app.ImageSourceCoords]  = IRfromCuboid(app.roomDimensions, app.Source, app.Receiver, app.t_reverb, app.Wallcoefs, app.f_s);
        cla(app.RoomPlot);
        if app.PlotimagesourcesCheckBox.Value
            plotImageSources(app.roomDimensions, app.Receiver, app.Source, app.ImageSourceCoords, app.ShowraytracesCheckBox.Value, app.RoomPlot);
        else
            plotRoom(app.roomDimensions, app.Receiver, app.Source, app.RoomPlot);
        end
        end

        % Value changed function: PlotimagesourcesCheckBox
        function PlotimagesourcesCheckBoxValueChanged(app, event)
            if ~isempty(app.roomDimensions) && ~isempty(app.Source) && ~isempty(app.Receiver)
                if true == app.PlotimagesourcesCheckBox.Value
                    cla(app.RoomPlot);
                    plotImageSources(app.roomDimensions, app.Receiver, app.Source, app.ImageSourceCoords, app.ShowraytracesCheckBox.Value, app.RoomPlot);
                else
                    cla(app.RoomPlot);
                    plotRoom(app.roomDimensions, app.Receiver, app.Source, app.RoomPlot);
                end
            end
        end

        % Value changed function: ShowraytracesCheckBox
        function ShowraytracesCheckBoxValueChanged(app, event)
            if ~isempty(app.roomDimensions) && ~isempty(app.Source) && ~isempty(app.Receiver)
                if true == app.PlotimagesourcesCheckBox.Value
                    cla(app.RoomPlot);
                    plotImageSources(app.roomDimensions, app.Receiver, app.Source, app.ImageSourceCoords, app.ShowraytracesCheckBox.Value, app.RoomPlot);
                else
                    cla(app.RoomPlot);
                    plotRoom(app.roomDimensions, app.Receiver, app.Source, app.RoomPlot);
                end
            end            
        end

        % Button pushed function: UpdateButton
        function UpdateButtonPushed(app, event)
            if ~isempty(app.inputAudioData)
                len = length(app.inputAudioData);
                t = (0:len-1)*(1 / app.f_s);
                plot(app.InputTimeDomainAxis, t, app.inputAudioData);                
                y_freqDomain = fft(app.inputAudioData);
                plot(app.InputFrequencyDomainAxis, (0:len-1)*(app.f_s / len), abs(fftshift(y_freqDomain)));
            end

            if ~isempty(app.combinedRoomImpulseResponse)
                len = length(app.combinedRoomImpulseResponse);
                t = (0:len-1)*(1 / app.f_s);
                plot(app.ImpulseResponseTimeDomainAxis, t, app.combinedRoomImpulseResponse);                
                y_freqDomain = fft(app.combinedRoomImpulseResponse);
                plot(app.ImpulseResponseFrequencyDomainAxis, (0:len-1)*(app.f_s / len), abs(fftshift(y_freqDomain)));
            end

            if ~isempty(app.outputAudioData)
                len = length(app.outputAudioData);
                t = (0:len-1)*(1 / app.f_s);
                plot(app.OutputTimeDomainAxis, t, app.outputAudioData);                
                y_freqDomain = fft(app.outputAudioData);
                plot(app.OutputFrequencyDomainAxis, (0:len-1)*(app.f_s / len), abs(fftshift(y_freqDomain)));
            elseif isempty(app.outputAudioData) && ~isempty(app.inputAudioData)
                len = length(app.inputAudioData);
                t = (0:len-1)*(1 / app.f_s);
                plot(app.OutputTimeDomainAxis, t, app.inputAudioData);                
                y_freqDomain = fft(app.inputAudioData);
                plot(app.OutputFrequencyDomainAxis, (0:len-1)*(app.f_s / len), abs(fftshift(y_freqDomain)));
            end
        end

        % Button pushed function: LoadHRIRSetButton
        function LoadHRIRSetButtonPushed(app, event)
            app.HrirFullSet = readHRIR("./impulse_responses//HRTF_full/");
            if ~isempty(app.HrirFullSet) && ~isempty(app.f_s)
                for i = 1:length(app.HrirFullSet(:,1))
                    for j = 1:length(app.HrirFullSet{i})
                        app.HrirFullSet{i}{j} = resample(app.HrirFullSet{i}{j}, app.f_s, 44100);
                    end
                end
            end
            app.HrirFullSet = transpose(app.HrirFullSet);
            app.HrirStatusField.Value = "HRIR set loaded";
        end

        % Button pushed function: RecordPositionsButton
        function RecordPositionsButtonPushed(app, event)
        if false == app.boRecordingPositions
                if isempty(app.roomDimensions) || isempty(app.Source) || isempty(app.Receiver)
                    app.RecordPosStatusField.Value = "failed";
                else
                    app.boRecordingPositions = true;
                    app.RecordPosStatusField.Value = "recording";
                    app.RecordPositionsButton.Visible = "off";
                    app.StopPosRecordingButton.Visible = "on";
                    indexCount = 1;
                    paddedSamples = (app.f_s / 2) - 1;
                    while  true == app.boRecordingPositions
                        app.recordedSourceCoords(indexCount:indexCount + paddedSamples, :) = repmat(app.Source, (app.f_s / 2), 1);
                        app.recordedReceiverCoords(indexCount:indexCount + paddedSamples, :) = repmat(app.Receiver, (app.f_s / 2), 1);
                        indexCount = indexCount + (app.f_s / 2);
                        pause(0.5);
                    end
                end
        else

        end
        end

        % Button pushed function: StopPosRecordingButton
        function StopPosRecordingButtonPushed(app, event)
            app.boRecordingPositions = false;
            app.StopPosRecordingButton.Visible = "off";
            app.RecordPositionsButton.Visible = "on";
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @KeyPressCallback, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.PlotsTab = uitab(app.TabGroup);
            app.PlotsTab.Title = 'Plots';
            app.InputTimeDomainAxis = uiaxes(app.PlotsTab);
            title(app.InputTimeDomainAxis, 'Input Time Domain Signal')
            xlabel(app.InputTimeDomainAxis, 'X')
            ylabel(app.InputTimeDomainAxis, 'Y')
            zlabel(app.InputTimeDomainAxis, 'Z')
            app.InputFrequencyDomainAxis = uiaxes(app.PlotsTab);
            title(app.InputFrequencyDomainAxis, 'Input Frequency Domain')
            xlabel(app.InputFrequencyDomainAxis, 'X')
            ylabel(app.InputFrequencyDomainAxis, 'Y')
            zlabel(app.InputFrequencyDomainAxis, 'Z')
            app.ImpulseResponseTimeDomainAxis = uiaxes(app.PlotsTab);
            title(app.ImpulseResponseTimeDomainAxis, 'Impulse Response Time Domain')
            xlabel(app.ImpulseResponseTimeDomainAxis, 'X')
            ylabel(app.ImpulseResponseTimeDomainAxis, 'Y')
            zlabel(app.ImpulseResponseTimeDomainAxis, 'Z')
            app.ImpulseResponseFrequencyDomainAxis = uiaxes(app.PlotsTab);
            title(app.ImpulseResponseFrequencyDomainAxis, 'Impulse Response Frequency Domain')
            xlabel(app.ImpulseResponseFrequencyDomainAxis, 'X')
            ylabel(app.ImpulseResponseFrequencyDomainAxis, 'Y')
            zlabel(app.ImpulseResponseFrequencyDomainAxis, 'Z')
            app.OutputTimeDomainAxis = uiaxes(app.PlotsTab);
            title(app.OutputTimeDomainAxis, 'Output Time Domain Signal')
            xlabel(app.OutputTimeDomainAxis, 'X')
            ylabel(app.OutputTimeDomainAxis, 'Y')
            zlabel(app.OutputTimeDomainAxis, 'Z')
            app.OutputFrequencyDomainAxis = uiaxes(app.PlotsTab);
            title(app.OutputFrequencyDomainAxis, 'Output Frequency Domain')
            xlabel(app.OutputFrequencyDomainAxis, 'X')
            ylabel(app.OutputFrequencyDomainAxis, 'Y')
            zlabel(app.OutputFrequencyDomainAxis, 'Z')
            app.UpdateButton = uibutton(app.PlotsTab, 'push');
            app.UpdateButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateButtonPushed, true);
            app.UpdateButton.Text = 'Update ';
            app.RoomAcousticalRenderingTab = uitab(app.TabGroup);
            app.RoomAcousticalRenderingTab.Title = 'Room Acoustical Rendering';
            app.RoomPlot = uiaxes(app.RoomAcousticalRenderingTab);
            title(app.RoomPlot, 'Room')
            xlabel(app.RoomPlot, 'X')
            ylabel(app.RoomPlot, 'Y')
            zlabel(app.RoomPlot, 'Z')
            app.RenderCuboidRoomButton = uibutton(app.RoomAcousticalRenderingTab, 'push');
            app.RenderCuboidRoomButton.ButtonPushedFcn = createCallbackFcn(app, @RenderCuboidRoomButtonPushed, true);
            app.RenderCuboidRoomButton.Text = 'Render Cuboid Room';
            app.SourceLabel = uilabel(app.RoomAcousticalRenderingTab);
            app.SourceLabel.Text = 'Source:';
            app.XEditFieldLabel = uilabel(app.RoomAcousticalRenderingTab);
            app.XEditFieldLabel.Text = 'X:';
            app.SourceXField = uieditfield(app.RoomAcousticalRenderingTab, 'numeric');
            app.YEditFieldLabel = uilabel(app.RoomAcousticalRenderingTab);
            app.YEditFieldLabel.Text = 'Y:';
            app.SourceYField = uieditfield(app.RoomAcousticalRenderingTab, 'numeric');
            app.ZEditFieldLabel = uilabel(app.RoomAcousticalRenderingTab);
            app.ZEditFieldLabel.Text = 'Z:';
            app.SourceZField = uieditfield(app.RoomAcousticalRenderingTab, 'numeric');
            app.ReceiverLabel = uilabel(app.RoomAcousticalRenderingTab);
            app.ReceiverLabel.Text = 'Receiver:';
            app.XEditField_4Label = uilabel(app.RoomAcousticalRenderingTab);
            app.XEditField_4Label.Text = 'X:';
            app.ReceiverXField = uieditfield(app.RoomAcousticalRenderingTab, 'numeric');
            app.YEditField_2Label = uilabel(app.RoomAcousticalRenderingTab);
            app.YEditField_2Label.Text = 'Y:';
            app.ReceiverYField = uieditfield(app.RoomAcousticalRenderingTab, 'numeric');
            app.ZEditField_2Label = uilabel(app.RoomAcousticalRenderingTab);
            app.ZEditField_2Label.Text = 'Z:';
            app.ReceiverZField = uieditfield(app.RoomAcousticalRenderingTab, 'numeric');
            app.ApplyCoordsButton = uibutton(app.RoomAcousticalRenderingTab, 'push');
            app.ApplyCoordsButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyCoordsButtonPushed, true);
            app.ApplyCoordsButton.Text = 'Apply';
            app.KeystrokesButton = uibutton(app.RoomAcousticalRenderingTab, 'push');
            app.KeystrokesButton.ButtonPushedFcn = createCallbackFcn(app, @KeystrokesButtonPushed, true);
            app.KeystrokesButton.Text = 'Keystrokes';
            app.PlotimagesourcesCheckBox = uicheckbox(app.RoomAcousticalRenderingTab);
            app.PlotimagesourcesCheckBox.ValueChangedFcn = createCallbackFcn(app, @PlotimagesourcesCheckBoxValueChanged, true);
            app.PlotimagesourcesCheckBox.Text = 'Plot image sources';
            app.ShowraytracesCheckBox = uicheckbox(app.RoomAcousticalRenderingTab);
            app.ShowraytracesCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowraytracesCheckBoxValueChanged, true);
            app.ShowraytracesCheckBox.Text = 'Show raytraces';
            app.RecordPositionsButton = uibutton(app.RoomAcousticalRenderingTab, 'push');
            app.RecordPositionsButton.ButtonPushedFcn = createCallbackFcn(app, @RecordPositionsButtonPushed, true);
            app.RecordPositionsButton.Text = 'Record Positions';
            app.LoadHRIRSetButton = uibutton(app.RoomAcousticalRenderingTab, 'push');
            app.LoadHRIRSetButton.ButtonPushedFcn = createCallbackFcn(app, @LoadHRIRSetButtonPushed, true);
            app.LoadHRIRSetButton.Text = 'Load HRIR Set';
            app.HrirStatusField = uieditfield(app.RoomAcousticalRenderingTab, 'text');
            app.RecordPosStatusField = uieditfield(app.RoomAcousticalRenderingTab, 'text');
            app.StopPosRecordingButton = uibutton(app.RoomAcousticalRenderingTab, 'push');
            app.StopPosRecordingButton.ButtonPushedFcn = createCallbackFcn(app, @StopPosRecordingButtonPushed, true);
            app.StopPosRecordingButton.Visible = 'off';
            app.StopPosRecordingButton.Text = 'Stop';
            app.SpatializationTab = uitab(app.TabGroup);
            app.SpatializationTab.Title = 'Spatialization';
            app.SenderReceiverTab = uitab(app.TabGroup);
            app.SenderReceiverTab.Title = 'Sender / Receiver ';
            app.InputDropDownLabel = uilabel(app.UIFigure);
            app.InputDropDownLabel.Text = 'Input';
            app.InputDropDown = uidropdown(app.UIFigure);
            app.InputDropDown.Items = {'Microphone', 'File', 'Real-Time'};
            app.InputDropDown.ValueChangedFcn = createCallbackFcn(app, @InputDropDownValueChanged, true);
            app.InputDropDown.Value = 'Microphone';
            app.OutputDropDownLabel = uilabel(app.UIFigure);
            app.OutputDropDownLabel.Text = 'Output';
            app.OutputDropDown = uidropdown(app.UIFigure);
            app.OutputDropDown.Items = {'Speaker', 'File'};
            app.OutputDropDown.ValueChangedFcn = createCallbackFcn(app, @OutputDropDownValueChanged, true);
            app.OutputDropDown.Value = 'Speaker';
            app.RecordButton = uibutton(app.UIFigure, 'push');
            app.RecordButton.ButtonPushedFcn = createCallbackFcn(app, @RecordButtonPushed, true);
            app.RecordButton.Text = 'Record';
            app.LoadFileButton = uibutton(app.UIFigure, 'push');
            app.LoadFileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadFileButtonPushed, true);
            app.LoadFileButton.Visible = 'off';
            app.LoadFileButton.Text = 'Load file';
            app.RealTimeButton = uibutton(app.UIFigure, 'push');
            app.RealTimeButton.Visible = 'off';
            app.RealTimeButton.Text = 'Button2';
            app.InputStatusField = uieditfield(app.UIFigure, 'text');
            app.InputStatusField.Value = 'ready';
            app.OutputButton = uibutton(app.UIFigure, 'push');
            app.OutputButton.ButtonPushedFcn = createCallbackFcn(app, @OutputButtonPushed, true);
            app.OutputButton.Text = 'Output';
            app.OutputStatusField = uieditfield(app.UIFigure, 'text');
            app.WriteFileButton = uibutton(app.UIFigure, 'push');
            app.WriteFileButton.ButtonPushedFcn = createCallbackFcn(app, @WriteFileButtonPushed, true);
            app.WriteFileButton.Visible = 'off';
            app.WriteFileButton.Text = 'Write file';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI_v2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end