classdef InputOutputAudio < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        ConvolutionTimeField      matlab.ui.control.EditField
        InputDropDown_3           matlab.ui.control.DropDown
        ConvolutiontypeLabel      matlab.ui.control.Label
        ConvolutionField          matlab.ui.control.EditField
        InputDropDown_2           matlab.ui.control.DropDown
        Inputsource2Label         matlab.ui.control.Label
        ReadAudioField_2          matlab.ui.control.EditField
        ReadAudioButton_2         matlab.ui.control.Button
        ConvolutionButton         matlab.ui.control.Button
        StartStopRecordingButton  matlab.ui.control.Button
        WriteAudioField           matlab.ui.control.EditField
        ReadAudioField            matlab.ui.control.EditField
        WriteAudioButton          matlab.ui.control.Button
        ReadAudioButton           matlab.ui.control.Button
        OutputDropDown            matlab.ui.control.DropDown
        OutputDropDownLabel       matlab.ui.control.Label
        InputDropDown             matlab.ui.control.DropDown
        InputsourceDropDownLabel  matlab.ui.control.Label
        FreqDomainAxes_2          matlab.ui.control.UIAxes
        TimeDomainAxes_2          matlab.ui.control.UIAxes
        FreqDomainAxes            matlab.ui.control.UIAxes
        TimeDomainAxes            matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        y_timeDomain % Description
        F_s
        y_timeDomain_2 % Description
        F_s_2
        y_timeDomain_3 % Description
        F_s_3
        boRecordingFlag1 = false;
        boRecordingFlag2 = false;
        recorder = audiorecorder(44100, 16, 1); % F_s = 44100, 16 bit quantization, 1 channel
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ReadAudioButton
        function ReadAudioButtonPushed(app, event)
            if "Audiofile" == app.InputDropDown.Value
                [file,path] = uigetfile({'*.wav; *.mp3; *.flac', 'Audio files (*.wav, *.mp3, *.flac)'});
                if isequal(file, 0)
                    app.ReadAudioField.Value = 'cancelled';
                else
                    try
                        [app.y_timeDomain, app.F_s] = audioread(fullfile(path,file));
                       app.ReadAudioField.Value = 'file read';
                    catch 
                        app.ReadAudioField.Value = 'failed';
                    end
                    L = length(app.y_timeDomain);
                    t=(0:L-1)*(1/app.F_s);

                    plot(app.TimeDomainAxes, t*app.F_s, app.y_timeDomain);

                    y_freqDomain = fft(app.y_timeDomain);
                    plot(app.FreqDomainAxes, (0:L-1)*(app.F_s/L), abs(fftshift(y_freqDomain)));
                end
            end
        end

        % Button pushed function: WriteAudioButton
        function WriteAudioButtonPushed(app, event)
            try
                if "finished" == app.ConvolutionField.Value
                    audio = app.y_timeDomain_3;
                else
                    audio = app.y_timeDomain;
                end

                if "Speaker" == app.OutputDropDown.Value
                    soundsc(audio, app.F_s);
                    app.WriteAudioField.Value = "played";
                elseif "Free Lossless Audio Codec (flac)" == app.OutputDropDown.Value
                    audiowrite("output.flac", audio, app.F_s, 'BitsPerSample', 24, 'Comment', "Flac output file");
                    app.WriteAudioField.Value = "flac file created";
                elseif "Microsoft WAVE sound (wav)" == app.OutputDropDown.Value
                    audiowrite("output.wav", audio, app.F_s);
                    app.WriteAudioField.Value = "wav file created";
                end
            catch
                app.WriteAudioField.Value = "failed";
            end
        end

        % Value changed function: InputDropDown
        function InputDropDownValueChanged(app, event)
            if "Microphone" == app.InputDropDown.Value
                app.StartStopRecordingButton.Visible = "on";
                app.ReadAudioButton.Visible = "off";
            else
                app.StartStopRecordingButton.Visible = "off";
                app.ReadAudioButton.Visible = "on";
            end
            app.ReadAudioField.Value = "";
            cla(app.TimeDomainAxes);
            cla(app.FreqDomainAxes);
            clearvars
        end

        % Value changed function: OutputDropDown
        function OutputDropDownValueChanged(app, event)
            app.WriteAudioField.Value = "";            
        end

        % Button pushed function: StartStopRecordingButton
        function StartStopRecordingButtonPushed(app, event)
            if false == app.boRecordingFlag1
                app.ReadAudioField.Value = "recording...";
                app.F_s = 44100;
                record(app.recorder, app.F_s);
                app.boRecordingFlag1 = true;                      
            elseif true == app.boRecordingFlag1
                stop(app.recorder);
                app.ReadAudioField.Value = "finished recordning";
                app.boRecordingFlag1 = false;
                app.y_timeDomain = getaudiodata(app.recorder);
            end
        end

        % Button pushed function: ConvolutionButton
        function ConvolutionButtonPushed(app, event)
            try
               audio1 = mean(app.y_timeDomain, 2);
               audio2 = mean(app.y_timeDomain_2, 2);
               if "Fast convolution" == app.InputDropDown_3.Value
                   tic
                   app.y_timeDomain_3 = fftConv(audio1,audio2);
                   time = num2str(toc);
                   app.ConvolutionTimeField.Value = strcat("Time: ", time,"s");
               elseif "Time Domain Convolution" == app.InputDropDown_3.Value
                   tic
                   app.y_timeDomain_3 = conv(audio1,audio2);
                   time = num2str(toc);
                   app.ConvolutionTimeField.Value = strcat("Time: ", time,"s");
               end
               app.ConvolutionField.Value = "finished";
            catch
               app.ConvolutionField.Value = "failed";
            end
          L = length(app.y_timeDomain_3);
          t=(0:L-1)*(1/app.F_s);

          plot(app.TimeDomainAxes_2, t*app.F_s, app.y_timeDomain_3);

          y_freqDomain = fft(app.y_timeDomain_3);
          plot(app.FreqDomainAxes_2, (0:L-1)*(app.F_s/L), abs(fftshift(y_freqDomain)));
        end

        % Callback function
        function StartStopRecordingButton_2Pushed(app, event)
            if false == app.boRecordingFlag2
                app.F_s_2 = 44100;
                app.ReadAudioField_2.Value = "recording...";
                record(app.recorder, app.F_s_2);
                app.boRecordingFlag2 = true;                      
            elseif true == app.boRecordingFlag2
                stop(app.recorder);
                app.ReadAudioField_2.Value = "finished recordning";
                app.boRecordingFlag2 = false;
                app.y_timeDomain_2  = getaudiodata(app.recorder);
            end
        end

        % Button pushed function: ReadAudioButton_2
        function ReadAudioButton_2Pushed(app, event)
            if "Audiofile" == app.InputDropDown_2.Value
                [file_2,path_2] = uigetfile({'*.wav; *.mp3; *.flac', 'Audio files (*.wav, *.mp3, *.flac)'});
                if isequal(file_2, 0)
                    app.ReadAudioField_2.Value = 'cancelled';
                else
                    try
                       [app.y_timeDomain_2, app.F_s_2] = audioread(fullfile(path_2,file_2));
                       app.ReadAudioField_2.Value = 'file read';
                    catch
                        app.ReadAudioField_2.Value = 'failed';
                    end
                    
                    %L = length(app.y_timeDomain_2);
                    %t=(0:L-1)*(1/app.F_s);

                    %plot(app.TimeDomainAxes, t*app.F_s, app.y_timeDomain_2);

                    %y_freqDomain = fft(app.y_timeDomain_2);
                    %plot(app.FreqDomainAxes, (0:L-1)*(app.F_s/L), abs(fftshift(y_freqDomain)));
                end
            end
        end

        % Value changed function: InputDropDown_2
        function InputDropDown_2ValueChanged(app, event)
            if "File" == app.InputDropDown_2.Value
                app.ReadAudioButton_2.Visible = "on";
            elseif "St. Andrews London" == app.InputDropDown_2.Value
                app.ReadAudioButton_2.Visible = "off";
                [app.y_timeDomain_2, app.F_s_2] = audioread(fullfile("./impulse_responses", "st-andrews-church.wav"));
            elseif "Canyon" == app.InputDropDown_2.Value
                app.ReadAudioButton_2.Visible = "off";
                [app.y_timeDomain_2, app.F_s_2] = audioread(fullfile("./impulse_responses", "canyon_pop_close_center_single.wav"));
            elseif "Echo" == app.InputDropDown_2.Value
                app.ReadAudioButton_2.Visible = "off";
                last=length(app.y_timeDomain);
                app.y_timeDomain_2 = zeros(last,1);
                app.y_timeDomain_2(1) = 1;
                app.y_timeDomain_2(last-1) = 1;
                app.F_s_2 = app.F_s;
            end
            app.ReadAudioField_2.Value = "";
            %cla(app.TimeDomainAxes);
            %cla(app.FreqDomainAxes);
            %clearvars
        
        end

        % Value changed function: InputDropDown_3
        function InputDropDown_3ValueChanged(app, event)
            
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TimeDomainAxes = uiaxes(app.UIFigure);
            title(app.TimeDomainAxes, 'Time domain')
            xlabel(app.TimeDomainAxes, 't / s')
            ylabel(app.TimeDomainAxes, 'Amplitude')
            zlabel(app.TimeDomainAxes, 'Z')
            app.FreqDomainAxes = uiaxes(app.UIFigure);
            title(app.FreqDomainAxes, 'Frequency domain')
            xlabel(app.FreqDomainAxes, 'f / Hz')
            ylabel(app.FreqDomainAxes, 'Magnitude')
            zlabel(app.FreqDomainAxes, 'Z')
            app.TimeDomainAxes_2 = uiaxes(app.UIFigure);
            title(app.TimeDomainAxes_2, 'Time domain - Impulse Response')
            xlabel(app.TimeDomainAxes_2, 't / s')
            ylabel(app.TimeDomainAxes_2, 'Amplitude')
            zlabel(app.TimeDomainAxes_2, 'Z')
            app.FreqDomainAxes_2 = uiaxes(app.UIFigure);
            title(app.FreqDomainAxes_2, 'Frequency domain - impulse response')
            xlabel(app.FreqDomainAxes_2, 'f / Hz')
            ylabel(app.FreqDomainAxes_2, 'Magnitude')
            zlabel(app.FreqDomainAxes_2, 'Z')
            app.InputsourceDropDownLabel = uilabel(app.UIFigure);
            app.InputsourceDropDownLabel.Text = 'Input source';
            app.InputDropDown = uidropdown(app.UIFigure);
            app.InputDropDown.Items = {'Audiofile', 'Microphone'};
            app.InputDropDown.ValueChangedFcn = createCallbackFcn(app, @InputDropDownValueChanged, true);
            app.InputDropDown.Value = 'Audiofile';
            app.OutputDropDownLabel = uilabel(app.UIFigure);
            app.OutputDropDownLabel.Text = 'Output ';
            app.OutputDropDown = uidropdown(app.UIFigure);
            app.OutputDropDown.Items = {'Speaker', 'Free Lossless Audio Codec (flac)', 'Microsoft WAVE sound (wav)'};
            app.OutputDropDown.ValueChangedFcn = createCallbackFcn(app, @OutputDropDownValueChanged, true);
            app.OutputDropDown.Value = 'Speaker';
            app.ReadAudioButton = uibutton(app.UIFigure, 'push');
            app.ReadAudioButton.ButtonPushedFcn = createCallbackFcn(app, @ReadAudioButtonPushed, true);
            app.ReadAudioButton.Text = 'Read audio';
            app.WriteAudioButton = uibutton(app.UIFigure, 'push');
            app.WriteAudioButton.ButtonPushedFcn = createCallbackFcn(app, @WriteAudioButtonPushed, true);
            app.WriteAudioButton.Text = 'Write audio';
            app.ReadAudioField = uieditfield(app.UIFigure, 'text');
            app.WriteAudioField = uieditfield(app.UIFigure, 'text');
            app.StartStopRecordingButton = uibutton(app.UIFigure, 'push');
            app.StartStopRecordingButton.ButtonPushedFcn = createCallbackFcn(app, @StartStopRecordingButtonPushed, true);
            app.StartStopRecordingButton.Visible = 'off';
            app.StartStopRecordingButton.Text = 'Start / Stop ';
            app.ConvolutionButton = uibutton(app.UIFigure, 'push');
            app.ConvolutionButton.ButtonPushedFcn = createCallbackFcn(app, @ConvolutionButtonPushed, true);
            app.ConvolutionButton.Text = 'Convolution';
            app.ReadAudioButton_2 = uibutton(app.UIFigure, 'push');
            app.ReadAudioButton_2.ButtonPushedFcn = createCallbackFcn(app, @ReadAudioButton_2Pushed, true);
            app.ReadAudioButton_2.Text = 'Read audio';
            app.ReadAudioField_2 = uieditfield(app.UIFigure, 'text');
            app.Inputsource2Label = uilabel(app.UIFigure);
            app.Inputsource2Label.Text = 'Impulse response';
            app.InputDropDown_2 = uidropdown(app.UIFigure);
            app.InputDropDown_2.Items = {'File', 'Echo', 'St. Andrews London', 'Canyon'};
            app.InputDropDown_2.ValueChangedFcn = createCallbackFcn(app, @InputDropDown_2ValueChanged, true);
            app.InputDropDown_2.Value = 'File';
            app.ConvolutionField = uieditfield(app.UIFigure, 'text');
            app.ConvolutiontypeLabel = uilabel(app.UIFigure);
            app.ConvolutiontypeLabel.Text = 'Convolution type';
            app.InputDropDown_3 = uidropdown(app.UIFigure);
            app.InputDropDown_3.Items = {'Fast convolution', 'Time Domain Convolution'};
            app.InputDropDown_3.ValueChangedFcn = createCallbackFcn(app, @InputDropDown_3ValueChanged, true);
            app.InputDropDown_3.Value = 'Fast convolution';
            app.ConvolutionTimeField = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = InputOutputAudio

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end